classdef AttitudeCalibrationInspector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EditField_17            matlab.ui.control.NumericEditField
        Label_16                matlab.ui.control.Label
        EditField_6             matlab.ui.control.NumericEditField
        Label_17                matlab.ui.control.Label
        EditField_10            matlab.ui.control.EditField
        Label_7                 matlab.ui.control.Label
        TextArea_2              matlab.ui.control.TextArea
        Label_15                matlab.ui.control.Label
        Button_12               matlab.ui.control.StateButton
        UITable4                matlab.ui.control.Table
        Button_11               matlab.ui.control.StateButton
        CheckBox                matlab.ui.control.CheckBox
        UITable3                matlab.ui.control.Table
        Button_10               matlab.ui.control.StateButton
        Button_9                matlab.ui.control.StateButton
        Button_8                matlab.ui.control.StateButton
        Button_7                matlab.ui.control.StateButton
        UITable2                matlab.ui.control.Table
        EditField_11            matlab.ui.control.NumericEditField
        Label_8                 matlab.ui.control.Label
        Button_6                matlab.ui.control.StateButton
        EditField_8             matlab.ui.control.EditField
        EditField_8Label        matlab.ui.control.Label
        Button_5                matlab.ui.control.StateButton
        Button_4                matlab.ui.control.StateButton
        SetPanel                matlab.ui.container.Panel
        EditField_21            matlab.ui.control.NumericEditField
        Label_21                matlab.ui.control.Label
        EditField_20            matlab.ui.control.NumericEditField
        Label_20                matlab.ui.control.Label
        magneticEditField       matlab.ui.control.NumericEditField
        magneticEditFieldLabel  matlab.ui.control.Label
        ON_OFFEditField         matlab.ui.control.NumericEditField
        ON_OFFEditFieldLabel    matlab.ui.control.Label
        EditField_19            matlab.ui.control.NumericEditField
        Label_19                matlab.ui.control.Label
        EditField_18            matlab.ui.control.NumericEditField
        Label_18                matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        Label_13                matlab.ui.control.Label
        EditField_15            matlab.ui.control.NumericEditField
        Label_12                matlab.ui.control.Label
        EditField_14            matlab.ui.control.NumericEditField
        Label_11                matlab.ui.control.Label
        EditField_13            matlab.ui.control.NumericEditField
        Label_10                matlab.ui.control.Label
        EditField_12            matlab.ui.control.NumericEditField
        Label_9                 matlab.ui.control.Label
        patternEditField        matlab.ui.control.EditField
        Label_6                 matlab.ui.control.Label
        EditField_7             matlab.ui.control.NumericEditField
        Label_5                 matlab.ui.control.Label
        EditField_5             matlab.ui.control.NumericEditField
        Label_14                matlab.ui.control.Label
        Button_3                matlab.ui.control.StateButton
        EditField_3             matlab.ui.control.NumericEditField
        Label_4                 matlab.ui.control.Label
        EditField_2             matlab.ui.control.NumericEditField
        Label_3                 matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        Label_2                 matlab.ui.control.Label
        Button_2                matlab.ui.control.StateButton
        UITable                 matlab.ui.control.Table
        EditField               matlab.ui.control.EditField
        Label                   matlab.ui.control.Label
        Button                  matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        Property % Description
        MagSelected
        RowsSelectedToBeDel
        FileList
        Total_Num
        Current_Num
        Current_File
        FileStringOfDB
        Cur_CodeName
        Cur_SerialNumber
        Rows
        Rowstotal
        Mag_table
        isConfirmed
        IDS
        FileList_acc
        CurrentRow = 11
    end

    methods (Access = private)
        function fileList = getMatchingFiles(app, rootDir, namePattern)
        % getMatchingFiles  递归搜索根目录下所有文件名中包含给定字符串的文件
        %   fileList = getMatchingFiles(rootDir, namePattern)
        %   输入：
        %       rootDir     - 搜索根目录 (字符串)
        %       namePattern - 文件名应包含的字符串 (如 '欧拉角数据.txt')
        %   输出：
        %       fileList    - 细胞数组，每个元素为匹配文件的完整路径
        %                     (如 'D:\TestData\00-001\20260628_134518_欧拉角数据.txt')
        
        if ~exist(rootDir, 'dir')
            error('根目录不存在: %s', rootDir);
        end
        
        fileList = searchRecursively(app, rootDir, namePattern);
        end
        
        % ----- 私有递归函数 -----
        function files = searchRecursively(app, currentDir, pattern)
            files = {};
            dirData = dir(currentDir);
            for i = 1:length(dirData)
                if strcmp(dirData(i).name, '.') || strcmp(dirData(i).name, '..')
                    continue;
                end
                fullName = fullfile(currentDir, dirData(i).name);
                if dirData(i).isdir
                    % 递归进入子文件夹
                    subFiles = searchRecursively(app, fullName, pattern);
                    files = [files, subFiles]; %#ok<AGROW>
                else
                    % 检查文件名是否包含指定字符串
                    if ~isempty(strfind(dirData(i).name, pattern))
                        files{end+1} = fullName; %#ok<AGROW>
                    end
                end
            end
        end
        
        % function idList = getIDsFromFolder(app, rootDir)
        % % getIDsFromFolder 递归全层级查找所有形如 00-001 的文件夹名称
        % % 复用 searchRecursively 遍历逻辑，统一文件/ID扫描深度
        % if ~exist(rootDir, 'dir')
        %     error('文件夹不存在: %s', rootDir);
        % end
        % 
        % idList = {};
        % % 复用已有的递归遍历函数，自定义筛选逻辑收集ID文件夹名
        % idList = scanIdByRecursive(app, rootDir);
        % 
        % % 优化排序：按数字大小排序，避免 10-001 排在 09-099 前面
        % if ~isempty(idList)
        %     idNum = cellfun(@(s) str2double(erase(s, '-')), idList);
        %     [~, sortIdx] = sort(idNum);
        %     idList = idList(sortIdx);
        % end
        % end
        function idList = getIDsFromFolder(app, rootDir)
            % getIDsFromFolder 递归全层级查找所有形如 00-001 的文件夹名称
            % 复用 searchRecursively 遍历逻辑，统一文件/ID扫描深度
            if ~exist(rootDir, 'dir')
                error('文件夹不存在: %s', rootDir);
            end
            
            idList = {};
            
            % ===== 新增：先判断根目录自身是否为ID目录 =====
            % 解决"浏览路径选到 01-011--20260908_165705 内部时，ID数=0"的问题
            [~, selfName] = fileparts(rootDir);
            selfMatch = regexp(selfName, '\d{2}-\d{3}', 'match');
            if ~isempty(selfMatch)
                idList{end+1} = selfMatch{end};
            end
            % =============================================
            
            % 复用已有的递归遍历函数，自定义筛选逻辑收集ID文件夹名
            idList = [idList, scanIdByRecursive(app, rootDir)];
            
            % 去重：防止根目录与子目录产生重复ID
            if ~isempty(idList)
                idList = unique(idList, 'stable');
            end
            
            % 优化排序：按数字大小排序，避免 10-001 排在 09-099 前面
            if ~isempty(idList)
                idNum = cellfun(@(s) str2double(erase(s, '-')), idList);
                [~, sortIdx] = sort(idNum);
                idList = idList(sortIdx);
            end
        end
        %% 内部嵌套递归函数，复用和searchRecursively一模一样的遍历结构
        function outIds = scanIdByRecursive(app, currentDir)
            outIds = {};
            dirData = dir(currentDir);
            for i = 1:length(dirData)
                name = dirData(i).name;
                if strcmp(name, '.') || strcmp(name, '..')
                    continue;
                end
                fullPath = fullfile(currentDir, name);
                if dirData(i).isdir
                    % 先递归深层子文件夹
                    subIds = scanIdByRecursive(app, fullPath);
                    outIds = [outIds, subIds]; %#ok<AGROW>
                    
                    % % 判断当前文件夹是否是合法ID格式
                    % if ~isempty(regexp(name, '\d{2}-\d{3}', 'once'))
                    %     outIds{end+1} = name; %#ok<AGROW>
                    % end
                    matchRes = regexp(name, '\d{2}-\d{3}', 'match');
                    if ~isempty(matchRes)
                        outIds{end+1} = matchRes{end}; % 
                    end
                end
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function writeLogToTextArea(app, lineStr)
            % lineStr 是char，统一转为string再拼接
            lineStr = string(lineStr);
            oldText = app.TextArea_2.Value;
            if isempty(oldText)
                newText = lineStr;
            else
                newText = [oldText; lineStr]; % 用分号纵向拼接，自动换行更美观
            end
            app.TextArea_2.Value = newText;
            scroll(app.TextArea_2, 'bottom'); % 自动滚动最新行
            drawnow limitrate; % 强制刷新界面，实时显示
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
         % ========== 第一步：App启动时自动加载历史配置参数 ==========
            % 配置文件路径（存在当前App目录，不会丢失）
            config_path = fullfile(fileparts(app.UIFigure.FileName), 'mag_config.mat');
            
            % 初始化UITable为table类型（解决之前的赋值报错问题）
            app.UITable.Data = table();
            
            % 如果配置文件存在，加载参数到输入框
            if exist(config_path, 'file')
                load(config_path);  % 加载保存的参数变量
                
                % 把参数赋值到对应的输入框（确保控件名称和你的实际名称一致！）
                app.EditField_15.Value = nnmu;
                app.patternEditField.Value = Txar;    % 磁起始列数
                app.EditField_5.Value = total_col;        % 总列数
                app.EditField_6.Value = unit_scale;       % 单位缩放
                app.EditField_7.Value = total_mag;        % 总磁场强度
                app.EditField_18.Value = imu_start_col;   % IMU起始列数（如果有）
                app.EditField_12.Value =  mag_threshold ;
                app.EditField_13.Value =  acc_threshold ;
                app.EditField_19.Value =  WindowsSize ;
                app.EditField_10.Value =  Number;   

                
                % 提示加载成功（可选，也可以注释掉避免弹窗打扰）
                %uialert(app.UIFigure, '已自动加载历史配置参数！', '加载成功');
            else
                % 首次启动无配置文件时，给默认值（可选，防止输入框为空）
                %%app.EditField_4.Value = 1;    % 磁起始列数默认值
                app.EditField_5.Value = 8;    % 总列数默认值
                app.EditField_6.Value = 1;    % 单位缩放默认值
                app.EditField_7.Value = 0.5;   % 总磁场强度默认值
                app.EditField_17.Value = 1;   % IMU起始列数默认值
                app.EditField_12.Value = 100;
                app.EditField_13.Value = 1;
                app.EditField_14.Value = 100;
                app.EditField_10.Value = "01-000";
            end

        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            % % 
            filterSpec = '';%'*mag*.dat';
            % 弹出文件选择对话框，获取用户选择的文件路径和文件名
            [fileName, pathName] = uigetfile(filterSpec, 'Select a.dat file containing mag');
            if isequal(fileName, 0) && isequal(pathName, 0)
                % 如果用户点击了取消按钮，这里可以进行相应提示等操作，比如输出提示信息
                %disp('User canceled the file selection.');
                app.EditField.Value = '';
            end
            app.EditField.Value = fullfile(pathName, fileName);
            disp (app.EditField.Value);
            focus(app.UIFigure);
            
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%公共参数%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        root = app.EditField.Value;
        ids = getIDsFromFolder(app, root);           % 获取排序后所有编号
        pattern = app.patternEditField.Value;
        allFiles = getMatchingFiles(app, root, pattern); % 获取所有数据文件
        app.Total_Num = length(allFiles);
        disp (allFiles);


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%公共参数end%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
if strcmp(app.DropDown.Value, "Yaw")
    % ========== 新增：和Pitch/Roll保持一致，初始化日志输出框 ==========
    app.TextArea_2.Value = "";
    uiLogCallback = @(singleLine) writeLogToTextArea(app, singleLine);
    
    % 校验：自定义编号数组长度必须和文件总数一致
    if length(ids) ~= app.Total_Num
        err_msg = sprintf('文件数量(%d) 与产品ID数量(%d)不匹配，无法生成汇总表',...
        length(allFiles), length(ids));
        errordlg(err_msg,'数据匹配错误');
        uiLogCallback(err_msg); % 错误同步打印到文本框
        return; % 终止批量流程
    end
    %% 1. Yaw标定全局参数配置
    tolerance    = app.EditField_13.Value;       % 角度容差
    cut_fr  = app.EditField_15.Value;            % 每段稳定数据前后各剔除帧数
    min_total_fr =  app.EditField_12.Value;      % 单段最小有效帧数
    angle_step   = 30;        % 角度步长
    point_num    = 12;        % 标定档位数量
    offset_low   = 4;         % 偏移下限
    offset_high  = 9;         % 偏移上限
    theory_ang = 0:angle_step:(point_num-1)*angle_step; % 0,30...330    

    ON_OFF = app.ON_OFFEditField.Value;
    magnetic  = app.magneticEditField.Value;


    %% 2. 构造表头（末尾新增“采集时间”列）
    raw_header = arrayfun(@(a) sprintf('原始读数%.0f°', a), theory_ang, 'UniformOutput', false);
    diff_header = arrayfun(@(a) sprintf('偏差差值%.0f°', a), theory_ang, 'UniformOutput', false);
    % 表头顺序：编号、有效位、RMS、12原始、12差值、时间（最后一列）
    header = [
        {'编号'},{'有效档位数(有效位)'},{'RMS(°)'},...
        raw_header,...
        diff_header,...
        {'处理时间'}
    ];
    summary = cell(length(allFiles)+1, length(header));
    summary(1,:) = header;

    % 写入启动日志
    startLog = sprintf('===== 航向批量标定开始，共%d个文件 =====', length(allFiles));
    uiLogCallback(startLog);
    fprintf('%s\n', startLog);

    for fileIdx = 1:length(allFiles)
        currFileName = allFiles{fileIdx};
        currProductID = ids{fileIdx};
        logLine1 = sprintf('正在处理文件：%s  产品ID：%s', currFileName, currProductID);
        fprintf('%s\n', logLine1);
        uiLogCallback(logLine1);
        
        % 每个文件处理时间戳
        procTime = datestr(now, 'yyyy-mm-dd HH:MM:SS');
        try
            result = calibrateOneFile(currFileName, tolerance, cut_fr, min_total_fr, ...
                app.EditField_5.Value, point_num, theory_ang, offset_low, offset_high, magnetic,ON_OFF );
        catch ME
            % 弹出GUI错误弹窗
            err_msg = sprintf('【%s】标定失败：\n%s', currProductID, ME.message);
            errordlg(err_msg, '文件标定异常');
            uiLogCallback(err_msg); % 错误写入输出框
            
            % 失败行填充：编号、有效位0、RMS NaN、12原始NaN、12差值NaN、时间
            failRow = [{currProductID}, {0}, {NaN}, repmat({NaN},1,point_num*2), {procTime}];
            summary(fileIdx+1, :) = failRow;
            continue;
        end

        % 原始值、差值转为单行元胞
        rawCell  = num2cell(result.raw_angle(:)');
        diffCell = num2cell(result.angle_diff(:)');
        % 填入一行数据（末尾追加处理时间）
        summary(fileIdx+1, :) = [
            {currProductID},...
            {result.nValid},...
            {result.rms_err},...
            rawCell, ...   % 原始稳态角度 37.7、67.7...
            diffCell,...   % 偏差差值 angle_diff
            {procTime}     % 最后一列：处理时间
        ];

        logLine2 = sprintf('=> 原始值示例：%.2f° | RMS=%.4f° | 有效档位=%d', ...
            result.raw_angle(1), result.rms_err, result.nValid);
        fprintf('%s\n', logLine2);
        uiLogCallback(logLine2);
    end

%% 3. 固定表名，有表追加、无表新建
outputPath = fullfile(app.EditField.Value, '批量航向标定汇总.xls');

% 2. 构建 finalData
if exist(outputPath, 'file')
    oldData = readcell(outputPath);
    % 先清洗旧数据基础missing
    oldData(ismissing(oldData)) = {''};
    if size(oldData,2) ~= size(summary,2)
        warning('列数不匹配（旧表 %d 列，新数据 %d 列），将覆盖旧文件。', ...
                size(oldData,2), size(summary,2));
        finalData = summary;
    else
        newRows = summary(2:end, :);
        finalData = [oldData; newRows];
    end
else
    finalData = summary;
end

% ===== 全局原位清洗finalData，彻底移除missing、嵌套元胞 =====
% 表格转元胞兼容
if istable(finalData)
    finalData = table2cell(finalData);
end

for k = 1:numel(finalData)
    val = finalData{k};
    if iscell(val)
        % 嵌套元胞转为逗号分隔字符串
        val = join(string(val),", ");
    end
    try
        strVal = string(val);
    catch
        strVal = string(formattedDisplayText(val));
    end
    % 清除missing
    strVal(ismissing(strVal)) = "";
    if numel(strVal) > 1
        strVal = join(strVal, ", ");
    end
    finalData{k} = char(strVal);
end

% 3. 写入（绝对安全）
writecell(finalData, outputPath, 'Sheet', '汇总结果');

% 4. 日志
endLog1 = sprintf('\n===============================');
endLog2 = sprintf('汇总表已更新：\n%s', outputPath);
endLog3 = sprintf('===============================\n');
fprintf('%s\n%s\n%s\n', endLog1, endLog2, endLog3);
uiLogCallback(endLog1);
uiLogCallback(endLog2);
uiLogCallback(endLog3);
elseif strcmp(app.DropDown.Value,"Pitch")
        app.TextArea_2.Value = "";
        uiLogCallback = @(singleLine) writeLogToTextArea(app, singleLine);
        CalcAttitudeAccuracyBatch(root, app.EditField_7.Value, uiLogCallback , app.EditField_18.Value, app.EditField_19.Value, app.EditField_20.Value*-1, app.EditField_21.Value*-1);
elseif strcmp(app.DropDown.Value,"Roll")
        app.TextArea_2.Value = "";
        uiLogCallback = @(singleLine) writeLogToTextArea(app, singleLine);
        CalcAttitudeAccuracyBatch(root, app.EditField_7.Value, uiLogCallback , app.EditField_18.Value, app.EditField_19.Value, app.EditField_20.Value*-1, app.EditField_21.Value*-1);
end

        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)

    % ========== 第二步：点击“设置”按钮时保存当前参数 ==========
    % 1. 读取当前输入框的参数（确保控件名称和你的实际名称一致！）
    nnmu = app.EditField_15.Value; 
    total_col = app.EditField_5.Value;        % 总列数
    Txar  = app.patternEditField.Value;
    unit_scale = app.EditField_6.Value;       % 单位缩放
    total_mag = app.EditField_7.Value;        % 总磁场强度
    imu_start_col = app.EditField_18.Value;   % IMU起始列数（如果有）
    mag_threshold = app.EditField_12.Value;
    acc_threshold = app.EditField_13.Value;
    WindowsSize = app.EditField_19.Value;
    Number = app.EditField_10.Value;
    % 2. 保存参数到.mat文件（路径和启动函数中一致）
    config_path = fullfile(fileparts(app.UIFigure.FileName), 'mag_config.mat');
    save(config_path, 'nnmu','total_col','Txar', 'unit_scale', 'total_mag', 'imu_start_col', 'mag_threshold', 'acc_threshold', 'WindowsSize', 'Number');

    % 3. 提示保存成功
    uialert(app.UIFigure, '参数已保存，下次启动App会自动加载！', '保存成功');

        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            value = app.Button_4.Value;

            if strtrim(app.EditField_8.Value) == ""
                uialert(app.UIFigure, '请先选择数据库路径！', '路径为空');
                return;  % 退出函数，不执行后续数据库写入
            end

            TableData = app.UITable.Data;
            app.FileStringOfDB = app.EditField_8.Value;
            app.Cur_CodeName =  app.patternEditField.Value;
            app.Cur_SerialNumber = app.EditField_10.Value ;
            disp(app.FileStringOfDB);
            disp(app.Cur_CodeName);
            disp(app.Cur_SerialNumber);
            

            SerialNumber = app.Cur_SerialNumber;
            CodeName = app.Cur_CodeName;
            table='加表小量程参数';
            colnames={'产品编号','产品代号','Acce_X1_T2_Y','Acce_X1_T1_Y','Acce_X0_T2_Y','Acce_X0_T1_Y','Acce_X3_T2_Z','Acce_X3_T1_Z'};
    
            rows = app.EditField_11.Value;
            if (~app.CheckBox.Value)  % ~ 表示“取反”，即未勾选时条件为真
                newdata={SerialNumber,CodeName,...
                    TableData(rows, 2), ...
                    TableData(rows, 5), ...
                    TableData(rows, 3), ...
                    TableData(rows, 6), ...
                    TableData(rows, 4), ...
                    TableData(rows, 7)};
            else
                newdata={SerialNumber,CodeName,...
                    TableData(rows, 8), ...
                    TableData(rows, 11), ...
                    TableData(rows, 9), ...
                    TableData(rows, 12), ...
                    TableData(rows, 10), ...
                    TableData(rows, 13)};
            end

            Flag = InsertDataBase(CodeName,SerialNumber,table,colnames,newdata,app.FileStringOfDB);
            if(Flag)
                uialert(app.UIFigure, '数据库写入成功！', '保存成功');
            else
                uialert(app.UIFigure, '数据库写入失败！', '失败');
            end
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
            value = app.Button_6.Value;
             % % 
            filterSpec = '*.*';%'*mag*.dat';
            % 弹出文件选择对话框，获取用户选择的文件路径和文件名
            [fileName, pathName] = uigetfile(filterSpec, 'Select a.dat file containing mag');
            if isequal(fileName, 0) && isequal(pathName, 0)
                app.EditField_8.Value = '';
            end
            app.EditField_8.Value = fullfile(pathName, fileName);
            focus(app.UIFigure);
            
        end

        % Value changed function: Button_5
        function Button_5ValueChanged(app, event)

            total_rows = height(app.UITable.Data);

            % 2. 空表保护
            if total_rows < 1
                return;
            end
        
            % 3. 初始化：如果当前行未定义或越界，先设为合法值（但不提前+1）
            if app.CurrentRow < 1 || app.CurrentRow > total_rows
                app.CurrentRow = 1;
            end
            
            % 4. 核心：下移一行（循环）
            app.CurrentRow = app.CurrentRow + 1;
            if app.CurrentRow > total_rows
                app.CurrentRow = 1; % 到末尾后回到第1行
            end
            
            % 5. 更新界面：显示当前行 + 高亮表格
            app.EditField_11.Value = app.CurrentRow;
            app.EditField_10.Value = app.IDS(app.CurrentRow);
            
            removeStyle(app.UITable);
            s = uistyle('BackgroundColor', [0.8, 0.9, 1]);
            addStyle(app.UITable, s, 'row', app.CurrentRow);
                   
        end

        % Value changed function: Button_7
        function Button_7ValueChanged(app, event)
               app.isConfirmed = true;
        end

        % Value changed function: Button_8
        function Button_8ValueChanged(app, event)
            if(~isempty(app.UITable2.Selection))
                app.MagSelected = app.UITable2.Selection;
                app.RowsSelectedToBeDel = unique(app.MagSelected(:,1));
                app.UITable2.Data(app.RowsSelectedToBeDel,:) = [];
                app.UITable2.Data(:,1) = (1:size(app.UITable2.Data,1))';
            end

            if(~isempty(app.UITable3.Selection))
                app.MagSelected = app.UITable3.Selection;
                app.RowsSelectedToBeDel = unique(app.MagSelected(:,1));
                app.UITable3.Data(app.RowsSelectedToBeDel,:) = [];
                app.UITable3.Data(:,1) = (1:size(app.UITable3.Data,1))';
                
            end
        end

        % Value changed function: Button_9
        function Button_9ValueChanged(app, event)
            app.UITable2.Selection = [];
            app.UITable3.Selection = [];
        end

        % Value changed function: Button_11
        function Button_11ValueChanged(app, event)
            value = app.Button_11.Value;

            app.UITable4 = [];
            
            app.FileList = getFilesRecursively(app.EditField.Value, 'titl_data.txt');
            Frame = getFilesRecursively(app.EditField.Value, 'frame_id_data.txt');
            
            app.Total_Num = length(app.FileList);
            app.Current_Num = 0;
            pattern = '\d{2}-\d{3}';
            custom_ids_cell = cell(size(app.FileList));
            count = 0;
            
            % 遍历文件列表，提取编号
            for i = 1:app.Total_Num
                % 使用 regexp 查找匹配项
                tokens = regexp(app.FileList{i}, pattern, 'match');
                if ~isempty(tokens)
                    count = count + 1;
                    custom_ids_cell{count} = tokens{1};
                end
            end
            % 截断数组至实际匹配数量，并转换为字符串数组
            custom_ids = string(custom_ids_cell(1:count));
            app.Rowstotal = app.Total_Num;
            % 显示结果
            disp(custom_ids);
            app.IDS = custom_ids;
            if(isempty(Frame))
                return;
            end
            for i = 1:app.Total_Num
                disp(app.FileList(i));
                mag_data = load(app.FileList{i});
                Frame_data = load(Frame{i});


                angle = atan2( mag_data (:,1), mag_data (:,2))* 180/pi;
                figure;plot(angle);grid on;
                hold on;plot(Frame_data,angle(Frame_data));
                m0 = mean(angle(Frame_data(1):Frame_data(2)));
                m1 = mean(angle(Frame_data(3):Frame_data(4)));
                m2 = mean(angle(Frame_data(5):Frame_data(6)));
                
                m3 = mean(angle(Frame_data(7):Frame_data(8)));

                M1 = [m0 m1 m2 m3];
                M1 = sort(M1);
                angleDelta = [M1(2)-M1(1),M1(3)-M1(2),M1(4)-M1(3),360-(M1(4)-M1(1))];
                angleErr = abs(angleDelta-90);
                angleErr_table(i, :) = angleErr; 
                 disp(angleErr_table);
            end

            maxErr = max(angleErr_table, [], 2);  % 每个文件的最大误差
            meanErr = mean(angleErr_table, 2);   % 每个文件的平均误差


            % 2.2 整合所有列：编号 + 4个角度差 + 平均值 + 最大值
            tableData = [custom_ids', angleErr_table, meanErr, maxErr];
            disp(tableData);
            % 2.3 转换为Table类型（UITable推荐格式，列名更易管理）
            data_now= table( ...
                tableData(:,1),  ...  % 编号
                tableData(:,2),  ...  % 角度差1
                tableData(:,3),  ...  % 角度差2
                tableData(:,4),  ...  % 角度差3
                tableData(:,5),  ...  % 角度差4
                tableData(:,6),  ...  % 平均值
                tableData(:,7),  ...  % 最大值
                'VariableNames', {'编号', '角度差1', '角度差2', '角度差3', '角度差4', '平均值', '最大值'} ...
            );
             disp(data_now);
            app.UITable4.Data = [app.UITable4.Data ; data_now];

            %% 3. 可选优化：设置UITable列宽自适应（更美观）
            app.UITable4.ColumnWidth = {'auto', 'auto', 'auto', 'auto', 'auto', 'auto', 'auto'};
            % app.UITable4.ColumnEditable = false;  % 禁止编辑（避免误改数据）

        end

        % Value changed function: Button_10
        function Button_10ValueChanged(app, event)
  if(~isempty(app.UITable))

        numberPart = app.EditField_10.Value;
        data = app.UITable.Data;
        % 将 data 转换为单元格数组
        disp(data);
        temp = app.EditField_11.Value;
        dataCell_Can = table2cell(data(temp,:)); % table → cell
        dataCell = [dataCell_Can(2), dataCell_Can(5), 0 , dataCell_Can(3),dataCell_Can(6), 0 , dataCell_Can(4), dataCell_Can(7)] ;
        % 将 numberPart 插入到 dataCell 中
        dataCell = [ {numberPart}, dataCell]; 
        disp(dataCell);
        % 定义文件名
        filename = 'Calibration.xlsm';
            % 检查文件是否存在
        if exist(filename, 'file') == 2
            writecell(dataCell, filename, 'WriteMode', 'append'); 
        else
            header ={'编号','Acce_X1_T2_Y','Acce_X1_T1_Y', '','Acce_X0_T2_Y','Acce_X0_T1_Y', '' ,'Acce_X3_T2_Z','Acce_X3_T1_Z'};
            % 先写入表头
            writecell(header, filename); 
            % 再写入数据，从第二行开始
            writecell(dataCell, filename, 'WriteMode', 'append'); 
        end
        % 显示保存成功的消息
        currentPath = pwd();  
        % 拼接完整保存路径
        fullPath = fullfile(currentPath, filename); 
        hMsg = msgbox(['数据已成功保存到 ' fullPath], '保存成功');
        hFig = ancestor(hMsg, 'figure');
        hFig.Position(3:4) = [500, 250];  % 窗口宽500，高250（可自定义）
        % 适配内部组件（可选，避免文字/按钮挤在一起）
        hPanel = findobj(hFig, 'Type', 'uipanel');
        if ~isempty(hPanel)
            hPanel.Position = [0.02, 0.05, 0.96, 0.9];
            hText = findobj(hPanel, 'Type', 'text');
            if ~isempty(hText)
                hText.Position = [0.03, 0.2, 0.94, 0.6];  % 调整文本位置
                hText.FontSize = 11;  % 可选：增大字体
            end
        end


  end
        end

        % Value changed function: Button_12
        function Button_12ValueChanged(app, event)
         if isempty(app.UITable4) || isempty(app.UITable4.Data)
            msgbox('数据表为空，无需写入', '提示');
            return;
        end
        
        % 2. 提取固定参数
        % numberPart = app.EditField_10.Value;  % 编号前缀/固定值
        data = app.UITable4.Data;            % 获取UITable4的所有数据（table类型）
        dataCell_All = table2cell(data);      % 转换为单元格数组（每行对应UITable4一行）
        rowNum = size(dataCell_All, 1);       % 获取UITable4的总行数
        filename = 'Verify.xlsm';             % 目标Excel文件名
        
        % 3. 先处理Excel表头（仅文件不存在时写入）
        if exist(filename, 'file') ~= 2
            header = {'编号','角度1','角度2', '角度3','角度4', '平均值' ,'最大值'};
            writecell(header, filename);  % 写入表头
        end
        
        % 4. 遍历UITable4每一行，逐行构造数据并写入
        for rowIdx = 1:rowNum
            % 提取当前行数据（和你原单行逻辑一致的字段映射）
            dataCell_Can = dataCell_All(rowIdx, :);  % 取第rowIdx行所有列
            % 构造单行数据结构（保持你原有的字段顺序+空值占位）
            dataCell = [
                dataCell_Can(1),   % Acce_X1_T2_Y
                dataCell_Can(2),   % Acce_X1_T1_Y 
                dataCell_Can(3),   % Acce_X0_T2_Y
                dataCell_Can(4),   % Acce_X0_T1_Y  
                dataCell_Can(5),   % Acce_X3_T2_Z
                dataCell_Can(6),    % Acce_X3_T1_Z
                dataCell_Can(7)
            ];

            % 插入numberPart作为第一列（编号列）
            dataCell = [dataCell'];
            disp(dataCell);
            % 追加写入当前行到Excel（核心：每次循环append一行）
            writecell(dataCell, filename, 'WriteMode', 'append');
            disp(dataCell);
        end
        
        % 5. 保存成功提示（保留你原有的样式优化）
        currentPath = pwd();  
        fullPath = fullfile(currentPath, filename); 
        hMsg = msgbox(['所有数据已成功保存到：' fullPath], '保存成功');
        hFig = ancestor(hMsg, 'figure');
        hFig.Position(3:4) = [500, 250];  % 窗口宽500，高250
        % 适配内部组件样式
        hPanel = findobj(hFig, 'Type', 'uipanel');
        if ~isempty(hPanel)
            hPanel.Position = [0.02, 0.05, 0.96, 0.9];
            hText = findobj(hPanel, 'Type', 'text');
            if ~isempty(hText)
                hText.Position = [0.03, 0.2, 0.94, 0.6];
                hText.FontSize = 11;
            end
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '浏览';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '路径';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '标定';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '当前文件';
            app.TextArea = uitextarea(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '文件个数';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '当前个数';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '设置';
            app.SetPanel = uipanel(app.UIFigure);
            app.SetPanel.Title = 'Set';
            app.Label_14 = uilabel(app.SetPanel);
            app.Label_14.Text = '航向列数';
            app.EditField_5 = uieditfield(app.SetPanel, 'numeric');
            app.EditField_5.Value = 5;
            app.Label_5 = uilabel(app.SetPanel);
            app.Label_5.Text = '航向精度';
            app.EditField_7 = uieditfield(app.SetPanel, 'numeric');
            app.EditField_7.Value = 0.5;
            app.Label_6 = uilabel(app.SetPanel);
            app.Label_6.Text = 'pattern';
            app.patternEditField = uieditfield(app.SetPanel, 'text');
            app.patternEditField.Value = '欧拉角数据.txt';
            app.Label_9 = uilabel(app.SetPanel);
            app.Label_9.Text = '最小有效帧';
            app.EditField_12 = uieditfield(app.SetPanel, 'numeric');
            app.EditField_12.Value = 100;
            app.Label_10 = uilabel(app.SetPanel);
            app.Label_10.Text = '角度容差';
            app.EditField_13 = uieditfield(app.SetPanel, 'numeric');
            app.EditField_13.Value = 2;
            app.Label_11 = uilabel(app.SetPanel);
            app.Label_11.Text = '滑窗大小';
            app.EditField_14 = uieditfield(app.SetPanel, 'numeric');
            app.EditField_14.Value = 100;
            app.Label_12 = uilabel(app.SetPanel);
            app.Label_12.Text = '前后帧长';
            app.EditField_15 = uieditfield(app.SetPanel, 'numeric');
            app.EditField_15.Value = 30;
            app.Label_13 = uilabel(app.SetPanel);
            app.Label_13.Text = '姿态角';
            app.DropDown = uidropdown(app.SetPanel);
            app.DropDown.Items = {'Yaw', 'Pitch', 'Roll'};
            app.DropDown.Value = 'Yaw';
            app.Label_18 = uilabel(app.SetPanel);
            app.Label_18.Text = '俯仰列数';
            app.EditField_18 = uieditfield(app.SetPanel, 'numeric');
            app.EditField_18.Value = 3;
            app.Label_19 = uilabel(app.SetPanel);
            app.Label_19.Text = '横滚列数';
            app.EditField_19 = uieditfield(app.SetPanel, 'numeric');
            app.EditField_19.Value = 4;
            app.ON_OFFEditFieldLabel = uilabel(app.SetPanel);
            app.ON_OFFEditFieldLabel.Text = 'ON_OFF';
            app.ON_OFFEditField = uieditfield(app.SetPanel, 'numeric');
            app.ON_OFFEditField.Value = 1;
            app.magneticEditFieldLabel = uilabel(app.SetPanel);
            app.magneticEditFieldLabel.Text = 'magnetic';
            app.magneticEditField = uieditfield(app.SetPanel, 'numeric');
            app.magneticEditField.Value = 7.5;
            app.Label_20 = uilabel(app.SetPanel);
            app.Label_20.Text = '俯仰方向';
            app.EditField_20 = uieditfield(app.SetPanel, 'numeric');
            app.EditField_20.Value = 1;
            app.Label_21 = uilabel(app.SetPanel);
            app.Label_21.Text = '横滚方向';
            app.EditField_21 = uieditfield(app.SetPanel, 'numeric');
            app.EditField_21.Value = 1;
            app.Button_4 = uibutton(app.UIFigure, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Text = '写入数据库';
            app.Button_5 = uibutton(app.UIFigure, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_5ValueChanged, true);
            app.Button_5.Text = '下一行';
            app.EditField_8Label = uilabel(app.UIFigure);
            app.EditField_8Label.Text = '路径';
            app.EditField_8 = uieditfield(app.UIFigure, 'text');
            app.Button_6 = uibutton(app.UIFigure, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Text = '浏览';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '当前行';
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_11.Value = 1;
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.Button_7 = uibutton(app.UIFigure, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button_7ValueChanged, true);
            app.Button_7.Text = '确认';
            app.Button_8 = uibutton(app.UIFigure, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button_8ValueChanged, true);
            app.Button_8.Text = '删行';
            app.Button_9 = uibutton(app.UIFigure, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.Button_9.Text = '清空';
            app.Button_10 = uibutton(app.UIFigure, 'state');
            app.Button_10.ValueChangedFcn = createCallbackFcn(app, @Button_10ValueChanged, true);
            app.Button_10.Text = '存入本地';
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '二套参数';
            app.Button_11 = uibutton(app.UIFigure, 'state');
            app.Button_11.ValueChangedFcn = createCallbackFcn(app, @Button_11ValueChanged, true);
            app.Button_11.Text = '验证';
            app.UITable4 = uitable(app.UIFigure);
            app.UITable4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable4.RowName = {};
            app.Button_12 = uibutton(app.UIFigure, 'state');
            app.Button_12.ValueChangedFcn = createCallbackFcn(app, @Button_12ValueChanged, true);
            app.Button_12.Text = '存入本地';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '输出窗';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '当前编号';
            app.EditField_10 = uieditfield(app.UIFigure, 'text');
            app.EditField_10.Value = '01-001';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '单位转换';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Value = 1;
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '选取列数';
            app.EditField_17 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_17.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AttitudeCalibrationInspector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end